classdef static_inverse_kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Inverse_Kinematics             matlab.ui.Figure
        ConstructiveparametersPanel    matlab.ui.container.Panel
        EdgeEditFieldLabel             matlab.ui.control.Label
        EdgeEditField                  matlab.ui.control.NumericEditField
        PositionerEdgeEditFieldLabel   matlab.ui.control.Label
        PositionerEdgeEditField        matlab.ui.control.NumericEditField
        WayLineEditFieldLabel          matlab.ui.control.Label
        WayLineEditField               matlab.ui.control.NumericEditField
        DriverRadiusEditFieldLabel     matlab.ui.control.Label
        DriverRadiusEditField          matlab.ui.control.NumericEditField
        DriverThicknessEditFieldLabel  matlab.ui.control.Label
        DriverThicknessEditField       matlab.ui.control.NumericEditField
        StateparametersPanel           matlab.ui.container.Panel
        dxLabel                        matlab.ui.control.Label
        dxSlider                       matlab.ui.control.Slider
        dySliderLabel                  matlab.ui.control.Label
        dySlider                       matlab.ui.control.Slider
        dzSliderLabel                  matlab.ui.control.Label
        dzSlider                       matlab.ui.control.Slider
        PhiSliderLabel                 matlab.ui.control.Label
        PhiSlider                      matlab.ui.control.Slider
        ThetaSliderLabel               matlab.ui.control.Label
        ThetaSlider                    matlab.ui.control.Slider
        PsiSliderLabel                 matlab.ui.control.Label
        PsiSlider                      matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          parameters = Parameters(app.EdgeEditField.Value ...
                                , app.PositionerEdgeEditField.Value ...
                                , app.WayLineEditField.Value ...
                                , app.DriverRadiusEditField.Value ...
                                , app.DriverThicknessEditField.Value);
          mechanism = Mechanism(parameters);
          state = State(app.dxSlider.Value ...
                      , app.dySlider.Value ...
                      , app.dzSlider.Value ...
                      , app.PhiSlider.Value ...
                      , app.ThetaSlider.Value ...
                      , app.PsiSlider.Value);
           
          mechanism.setState(state);
          mechanism.calculateState();
          
          drawContext = mechanism.draw();
          
          hold(app.UIAxes)
          zlabel(app.UIAxes, 'Z')
          plot3(app.UIAxes, drawContext.MechanismX, drawContext.MechanismY, drawContext.MechanismZ,'-go',...
                'LineWidth',4,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','r',...
                'MarkerSize',10);
          plot3(app.UIAxes, drawContext.CircleX, drawContext.CircleY, zeros(1, numel(drawContext.CircleX)),'-b', 'LineWidth',2);
          plot3(app.UIAxes, drawContext.LineX, drawContext.LineY, drawContext.LineZ,'-ro',...
                'LineWidth',4,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','r',...
                'MarkerSize',5);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Inverse_Kinematics = uifigure;
            app.Inverse_Kinematics.Name = 'Inverse Task of Kinematics';
            app.ConstructiveparametersPanel = uipanel(app.Inverse_Kinematics);
            app.ConstructiveparametersPanel.Title = 'Constructive parameters';
            app.EdgeEditFieldLabel = uilabel(app.ConstructiveparametersPanel);
            app.EdgeEditFieldLabel.Text = 'Edge';
            app.EdgeEditField = uieditfield(app.ConstructiveparametersPanel, 'numeric');
            app.EdgeEditField.Limits = [0 Inf];
            app.EdgeEditField.Value = 0.5;
            app.PositionerEdgeEditFieldLabel = uilabel(app.ConstructiveparametersPanel);
            app.PositionerEdgeEditFieldLabel.Text = 'Positioner Edge';
            app.PositionerEdgeEditField = uieditfield(app.ConstructiveparametersPanel, 'numeric');
            app.PositionerEdgeEditField.Limits = [0 Inf];
            app.PositionerEdgeEditField.Value = 0.2;
            app.WayLineEditFieldLabel = uilabel(app.ConstructiveparametersPanel);
            app.WayLineEditFieldLabel.Text = 'Way Line';
            app.WayLineEditField = uieditfield(app.ConstructiveparametersPanel, 'numeric');
            app.WayLineEditField.Limits = [0 Inf];
            app.WayLineEditField.Value = 0.8;
            app.DriverRadiusEditFieldLabel = uilabel(app.ConstructiveparametersPanel);
            app.DriverRadiusEditFieldLabel.Text = 'Driver Radius';
            app.DriverRadiusEditField = uieditfield(app.ConstructiveparametersPanel, 'numeric');
            app.DriverRadiusEditField.Limits = [0 Inf];
            app.DriverRadiusEditField.Value = 0.45;
            app.DriverThicknessEditFieldLabel = uilabel(app.ConstructiveparametersPanel);
            app.DriverThicknessEditFieldLabel.Text = 'Driver Thickness';
            app.DriverThicknessEditField = uieditfield(app.ConstructiveparametersPanel, 'numeric');
            app.DriverThicknessEditField.Limits = [0 Inf];
            app.DriverThicknessEditField.Value = 0.1;
            app.StateparametersPanel = uipanel(app.Inverse_Kinematics);
            app.StateparametersPanel.Title = 'State parameters';
            app.dxLabel = uilabel(app.StateparametersPanel);
            app.dxLabel.Text = 'dx';
            app.dxSlider = uislider(app.StateparametersPanel);
            app.dxSlider.Limits = [0 0.5];
            app.dySliderLabel = uilabel(app.StateparametersPanel);
            app.dySliderLabel.Text = 'dy';
            app.dySlider = uislider(app.StateparametersPanel);
            app.dySlider.Limits = [0 0.5];
            app.dzSliderLabel = uilabel(app.StateparametersPanel);
            app.dzSliderLabel.Text = 'dz';
            app.dzSlider = uislider(app.StateparametersPanel);
            app.dzSlider.Limits = [0 0.5];
            app.dzSlider.Value = 0.2;
            app.PhiSliderLabel = uilabel(app.StateparametersPanel);
            app.PhiSliderLabel.Text = 'Phi';
            app.PhiSlider = uislider(app.StateparametersPanel);
            app.PhiSlider.Limits = [0 360];
            app.ThetaSliderLabel = uilabel(app.StateparametersPanel);
            app.ThetaSliderLabel.Text = 'Theta';
            app.ThetaSlider = uislider(app.StateparametersPanel);
            app.ThetaSlider.Limits = [0 30];
            app.PsiSliderLabel = uilabel(app.StateparametersPanel);
            app.PsiSliderLabel.Text = 'Psi';
            app.PsiSlider = uislider(app.StateparametersPanel);
            app.PsiSlider.Limits = [0 30];
            app.UIAxes = uiaxes(app.Inverse_Kinematics);
            title(app.UIAxes, 'Visualizing of the mechanism')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = static_inverse_kinematics

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Inverse_Kinematics)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Inverse_Kinematics)
        end
    end
end